classdef OmniSpectrometer_wrapper_updated < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        LeftPanelGrid                   matlab.ui.container.GridLayout
        ExitButton                      matlab.ui.control.Button
        SIF                             matlab.ui.control.Label
        SIFLabel                        matlab.ui.control.Label
        SkyCondition                    matlab.ui.control.Label
        SkyConditionLabel               matlab.ui.control.Label
        CICV                            matlab.ui.control.Label
        CICVLabel                       matlab.ui.control.Label
        SZA                             matlab.ui.control.Label
        SZADiurnalLabel                 matlab.ui.control.Label
        MonitorTemperaturesButton       matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        StartLiveViewButton             matlab.ui.control.Button
        RunTime                         matlab.ui.control.Label
        RunTimeLabel                    matlab.ui.control.Label
        NumOfSpectrometersLabel         matlab.ui.control.Label
        ConnectSpectrometersButton      matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        PlotGrid                        matlab.ui.container.GridLayout
        CenterGrid                      matlab.ui.container.GridLayout
        SpectrometersGrid               matlab.ui.container.GridLayout
        Spectrometer4CheckBox           matlab.ui.control.CheckBox
        Spectrometer3CheckBox           matlab.ui.control.CheckBox
        Spectrometer2CheckBox           matlab.ui.control.CheckBox
        Spectrometer1CheckBox           matlab.ui.control.CheckBox
        MonitorTemperaturesPanel        matlab.ui.container.Panel
        TemperaturePanelGrid            matlab.ui.container.GridLayout
        GaugeGrid4                      matlab.ui.container.GridLayout
        GaugeLabel4                     matlab.ui.control.Label
        Gauge4                          matlab.ui.control.SemicircularGauge
        GaugeGrid3                      matlab.ui.container.GridLayout
        GaugeLabel3                     matlab.ui.control.Label
        Gauge3                          matlab.ui.control.SemicircularGauge
        GaugeGrid2                      matlab.ui.container.GridLayout
        GaugeLabel2                     matlab.ui.control.Label
        Gauge2                          matlab.ui.control.SemicircularGauge
        GaugeGrid1                      matlab.ui.container.GridLayout
        GaugeLabel1                     matlab.ui.control.Label
        Gauge1                          matlab.ui.control.SemicircularGauge
        SpectrometerTempLabel4          matlab.ui.control.Label
        SpectrometerTempLabel3          matlab.ui.control.Label
        SpectrometerTempLabel2          matlab.ui.control.Label
        SpectrometerTempLabel1          matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        TabGroupGrid                    matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        MainControlsTab                 matlab.ui.container.Tab
        MainGrid                        matlab.ui.container.GridLayout
        SaveRawSpectrumandBGButton      matlab.ui.control.Button
        GridLayout2                     matlab.ui.container.GridLayout
        SaveFolderButton                matlab.ui.control.Button
        SaveSpectrumButton              matlab.ui.control.Button
        ShowApparentReflectanceButton   matlab.ui.control.Button
        NoBGButton                      matlab.ui.control.Button
        SetBGButton                     matlab.ui.control.Button
        IntegrationTimesEditField       matlab.ui.control.NumericEditField
        SelectSpectrometerDropDown      matlab.ui.control.DropDown
        AutomaticButton                 matlab.ui.control.StateButton
        IntegrationTimesEditFieldLabel  matlab.ui.control.Label
        CalibrationTab                  matlab.ui.container.Tab
        CalibrationGrid                 matlab.ui.container.GridLayout
        ClearButton                     matlab.ui.control.Button
        FinishButton                    matlab.ui.control.Button
        SaveToFileButton                matlab.ui.control.Button
        LoadFromFileButton              matlab.ui.control.Button
        FiberDiameterEditField          matlab.ui.control.NumericEditField
        FiberDiameterEditField_2Label   matlab.ui.control.Label
        LampFileName                    matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.Button
        LampFileLabel                   matlab.ui.control.Label
        StoreBackgroundButton           matlab.ui.control.StateButton
        StoreReferenceButton            matlab.ui.control.StateButton
        SourceGroup                     matlab.ui.container.ButtonGroup
        NewCalibrationButton            matlab.ui.control.RadioButton
        GetIrradianceCalibrationfromFileButton  matlab.ui.control.RadioButton
        GeographicTab                   matlab.ui.container.Tab
        GeoGrid                         matlab.ui.container.GridLayout
        GeoConfirmButton                matlab.ui.control.Button
        GeoExportButton                 matlab.ui.control.Button
        GeoImportButton                 matlab.ui.control.Button
        TimeZoneEditField               matlab.ui.control.EditField
        TimeZoneEditFieldLabel          matlab.ui.control.Label
        AZMRotationEditField            matlab.ui.control.NumericEditField
        AZMRotationEditFieldLabel       matlab.ui.control.Label
        SlopeEditField                  matlab.ui.control.NumericEditField
        SlopeEditFieldLabel             matlab.ui.control.Label
        TemperatureEditField            matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel       matlab.ui.control.Label
        PressureEditField               matlab.ui.control.NumericEditField
        PressureEditFieldLabel          matlab.ui.control.Label
        ElevationEditField              matlab.ui.control.NumericEditField
        ElevationEditFieldLabel         matlab.ui.control.Label
        LongitudeEditField              matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel         matlab.ui.control.Label
        LatitudeEditField               matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel          matlab.ui.control.Label
        AdvanceTab                      matlab.ui.container.Tab
        AdvanceGrid                     matlab.ui.container.GridLayout
        SetToDefaultButton              matlab.ui.control.Button
        RimShadingAngleEditField        matlab.ui.control.NumericEditField
        RimShadingAngleEditFieldLabel   matlab.ui.control.Label
        AutoSaveIntervalEditField       matlab.ui.control.NumericEditField
        AutoSaveIntervalEditFieldLabel  matlab.ui.control.Label
        AutoSavePrefixEditField         matlab.ui.control.EditField
        AutoSavePrefixEditFieldLabel    matlab.ui.control.Label
        AutoIntegrationPeakEditField    matlab.ui.control.NumericEditField
        AutoIntegrationPeakEditFieldLabel  matlab.ui.control.Label
        ScanToAverageEditField          matlab.ui.control.NumericEditField
        AutoIntegrationAvgLabel         matlab.ui.control.Label
        MaxIntegrationTimeEditField     matlab.ui.control.NumericEditField
        MaxIntegrationTimeEditFieldLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        wrapper             % Create MATLAB Instrument OmniDriver object
        numOfSpectrometers
        spectrometerIndex
        integrationTime
        integrationTimeOld
        timeObj
        timeRun
        wavelength
        rawSpectrum
        bgSpectrum
        init
        activeIndex
        runTime
        isConnected
        isRunning
        isAuto
        legendLabel
        scanIndex
        scanMaxValues
        advanceSettings     % group
        calcPARIndex
        PARSubsequent
        defaultSavePath
        doy
        zenith
        absoluteIrradiance  % group
        isCalibrating
        finishedCalSpec
    end

    methods (Access = private)

        function [wavelengthData, spectralData] = changeSpectrometer(app)
            wavelengthData = cell(1, app.numOfSpectrometers);
            spectralData = cell(1, app.numOfSpectrometers);
            for i = app.activeIndex
                wavelengthData{i + 1} = app.wrapper.getWavelengths(i);
                spectralData{i + 1} = app.wrapper.getSpectrum(i);
                
                % Remove the first and last four outliers from data
                wavelengthData{i + 1} = wavelengthData{i + 1}(5:end-4);
                spectralData{i + 1} = spectralData{i + 1}(5:end-4);

                if app.finishedCalSpec(i + 1) && app.isCalibrating
                    spectralData{i + 1} = spectralData{i + 1} .* app.absoluteIrradiance.finishedCalFile{i + 1};
                end
            end
        end

        function LiveSpectrum(app, ~, ~)
            [wavelengthData, spectralData] = app.changeSpectrometer();
            
            app.wavelength = wavelengthData;
            app.rawSpectrum = spectralData;

            if app.init
                app.NoBGButtonPushed()
                app.IntegrationTimesEditFieldValueChanged()
                app.SetToDefaultButtonPushed()
                app.init = false;
            end

            bg = app.bgSpectrum;

            cla(app.UIAxes);
            hold(app.UIAxes, 'on')

            for i = app.activeIndex
                plot(wavelengthData{i + 1}, spectralData{i + 1} - bg{i + 1}, 'Parent', app.UIAxes);
            end

            hold(app.UIAxes, 'off')
            
            % Create a cell array of legend labels
            for i = 1:numel(app.activeIndex)
                legendLabels{i} = sprintf('%s', app.legendLabel(app.activeIndex(i) + 1));
            end
            
            % Show the legends
            legend(app.UIAxes, legendLabels);
            axis(app.UIAxes, 'tight')
        end

        function LiveTime(app, ~, ~)
            app.runTime = app.runTime + 1;
            
            totalSeconds = app.runTime;
            hours = floor(totalSeconds / 3600);
            minutes = floor(mod(totalSeconds, 3600) / 60);
            seconds = floor(mod(totalSeconds, 60));
            
            formattedTime = sprintf('%02d:%02d:%02d', hours, minutes, seconds);

            app.RunTime.Text = formattedTime;

            % The time-varying external function
            if mod(totalSeconds, 60) == 0
                app.GeoConfirmButtonPushed()
            end
  
            % Categorize sky conditions based on a combination of CV and CI
            if ~isnan(app.calcPARIndex)
                PAR = par(app.wavelength{app.calcPARIndex + 1}, ...
                    app.rawSpectrum{app.calcPARIndex + 1}, ...
                    app.integrationTime(app.calcPARIndex + 1));
                
                app.PARSubsequent = [app.PARSubsequent, PAR];
                
                if length(app.PARSubsequent) > 3
                    app.PARSubsequent = app.PARSubsequent(end-2:end);
                end
                
                % CV
                PAR_mean = mean(app.PARSubsequent);
                PAR_std = std(app.PARSubsequent);
                PAR_cv = PAR_std/PAR_mean;
    
                % CI
                tau = 0.75;
                PAR_clear = 1800;
                S_t_clear = 1150;
                PAR = 1000;
    
                PAR_ci = ci(app.doy, tau, app.PressureEditField.Value, app.zenith, ...
                    PAR_clear, S_t_clear, PAR);
    
                app.CICV.Text = [sprintf('%.2f', PAR_ci), ' / ', sprintf('%.2f', PAR_cv)];
                
                % Constraint
                if 0.9 <= PAR_ci && PAR_ci <= 1 && PAR_cv <= 0.05
                    app.SkyCondition.Text = 'Clear Sky';
                elseif 0.65 <= PAR_ci && PAR_ci < 0.9 && PAR_cv <= 0.05
                    app.SkyCondition.Text = 'Hazy Sky';
                elseif 0.65 < PAR_ci && PAR_cv >= 0.05
                    app.SkyCondition.Text = 'Variable Clouds';
                elseif PAR_ci <= 0.65
                    app.SkyCondition.Text = 'Heavy Clouds';
                else
                    app.SkyCondition.Text = 'Unknown';
                end
            end

            % Automatic saving of spectrum files every 300 seconds, 0 to stop
            if app.advanceSettings.autoSaveInterval == 0
            else
                if mod(app.runTime, app.advanceSettings.autoSaveInterval) == 0 && numel(app.activeIndex) > 0
                    AutoSaveSpectrum(app)
                end
    
                i = app.SelectSpectrometerDropDown.Value + 1;
                if app.isAuto(i)
                    % Queue the current max value
                    app.scanMaxValues(app.scanIndex) = max(app.rawSpectrum{i});
    
                    app.scanIndex = app.scanIndex + 1;
                    if app.scanIndex > app.advanceSettings.scanToAvg
                        app.scanIndex = 1;
                    end
                    
                    if app.scanIndex == 1
                        maxValuesMean = mean(app.scanMaxValues);
                        checkAutoIntegrationTime(app, i, maxValuesMean)
                        app.scanMaxValues = zeros(app.advanceSettings.scanToAvg, 1);
                    end
                end
            end
        end

        function AutoSaveSpectrum(app)
            wavelengthData = single(cell2mat(app.wavelength));
            rawSpectralData = single(cell2mat(app.rawSpectrum));
            bg = single(cell2mat(app.bgSpectrum));
            
            [infoData, ~] = getInfo(app, false, false);

            path = fullfile(app.defaultSavePath, ['AutoSave_', datestr(now, 'yymmdd')]);
            if ~isfolder(path)
                mkdir(path)
            end

            if ~isempty(app.advanceSettings.prefix)
                file = [app.advanceSettings.prefix, '_', ...
                    sprintf('%s.mat', datestr(now, 'HHMM'))];
            else
                file = sprintf('%s.mat', datestr(now, 'HHMM'));
            end
            
            save(fullfile(path, file), 'infoData', 'wavelengthData', 'rawSpectralData', 'bg');
        end

        function checkAutoIntegrationTime(app, i, maxValuesMean)
            app.IntegrationTimesEditField.Value = app.integrationTime(i) / 1000000;
            maxIntensity = app.wrapper.getMaximumIntensity(app.spectrometerIndex);
            safeIntensity = maxIntensity * app.advanceSettings.autoITpeak;
        
            if maxValuesMean > maxIntensity
                newITValue = app.IntegrationTimesEditField.Value / 2;
            else
                integrationTimeRatio = maxValuesMean / safeIntensity;
                newITValue = app.IntegrationTimesEditField.Value / integrationTimeRatio;
            end

            % Prevent large bias in the retrieved spectral reflectance from fast‐varying light conditions
            % a maximum integration time of 8s is set for any illumination conditions
            if newITValue > app.advanceSettings.maxIT
                app.IntegrationTimesEditField.Value = app.advanceSettings.maxIT;
            else
                app.IntegrationTimesEditField.Value = str2double(sprintf('%.3f', newITValue));
            end
            app.IntegrationTimesEditFieldValueChanged();
        end

        function [infoData, infoPath] = getInfo(app, file, path)
            
            if app.isCalibrating
                infoData = {'Date', 'Spectrometer', 'Integration Time (s)', 'Fiber (μm)'};
            else
                infoData = {'Channel', 'Spectrometer', 'Integration Time (s)'};
            end

            infoPath = '';

            for index = app.activeIndex
                i = index + 1;

                splitStr = strsplit(app.(['Spectrometer' num2str(i) 'CheckBox']).Text, ' ');
                spectrometerCH = splitStr{1};
                spectrometerSN = sprintf('QEP0%s', splitStr{2}(2:end-1));

                if app.isCalibrating
                    infoData = [infoData, ...
                        {datestr(now), spectrometerSN, ...
                        num2str(sprintf('%.2E', app.IntegrationTimesEditField.Value)), ...
                        num2str(sprintf('%.2f', app.absoluteIrradiance.fiberDiameter))}]; 
                else
                    infoData = [infoData, ...
                        {spectrometerCH, spectrometerSN, ...
                        num2str(sprintf('%.2E', app.integrationTime(i) / 1000000))}];
                end
            end
            if app.isCalibrating
                infoData = reshape(infoData, 4, numel(app.activeIndex) + 1);
            else
                infoData = reshape(infoData, 3, numel(app.activeIndex) + 1);
            end

            % additional suffix
            if file
                [~, baseFilename, ext] = fileparts(file);
                infoFilename = [baseFilename, '_info', ext];
                infoPath = fullfile(path, infoFilename);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            delete(gcp('nocreate'))
            parpool('threads');

            app.timeObj = timer(...
                'ExecutionMode', 'fixedSpacing', ...    % Run timer repeatedly
                'Period', 0.1, ...                      % Period is 0.1 second
                'BusyMode', 'queue', ...                % Queue timer callbacks when busy
                'TimerFcn', @app.LiveSpectrum);         % Specify callback function

            app.timeRun = timer( ...
                'ExecutionMode','fixedRate', ...
                'Period', 1, ...
                'BusyMode', 'queue', ...
                'TimerFcn', @app.LiveTime);
            
            app.numOfSpectrometers = 0;
            app.spectrometerIndex = 0;      % first spectrometer by default
            app.activeIndex = 0;
            app.init = true;
            app.runTime = 0;
            app.isConnected = false;
            app.isRunning = false;
            app.legendLabel = string.empty(0, 1);
            app.integrationTime = [];
            app.integrationTimeOld = [];
            app.isAuto = [];
            app.PARSubsequent = [];
            app.isCalibrating = 0;
            app.calcPARIndex = NaN;
            app.absoluteIrradiance.fiberDiameter = 355;
            app.finishedCalSpec = [0, 0, 0, 0];

            app.defaultSavePath = '../Save/';
            if ~isfolder(app.defaultSavePath)
                mkdir(app.defaultSavePath)
            end
            
        end

        % Button pushed function: ConnectSpectrometersButton
        function ConnectSpectrometersButtonPushed(app, event)
            function DefaultUiStates(app)
                app.StartLiveViewButton.Enable = 'off';
                app.MonitorTemperaturesButton.Enable = 'off';
                app.IntegrationTimesEditField.Editable = 'off';
                app.StopButton.Enable = 'off';
                app.SetBGButton.Enable = 'off';
                app.NoBGButton.Enable = 'off';
                app.SaveSpectrumButton.Enable = 'off';
                app.SaveRawSpectrumandBGButton.Enable = 'off';
                app.AutomaticButton.Enable = 'off';
                app.SelectSpectrometerDropDown.Enable = 'off';
                app.isConnected = false;
                app.isRunning = false;
                app.isCalibrating = false;
            end
            
            % clean up
            if app.numOfSpectrometers
                app.wrapper.closeAllSpectrometers();
            end
            
            javaClassPath = javaclasspath;
            if ~any(contains(javaClassPath, 'OmniDriver.jar'))
                javaaddpath('./src/OmniDriver.jar')
            end
            
            import('com.oceanoptics.omnidriver.api.wrapper.Wrapper');
            app.wrapper = Wrapper();
            app.numOfSpectrometers = app.wrapper.openAllSpectrometers();

            if ~app.numOfSpectrometers
                app.NumOfSpectrometersLabel.Text = 'No Devices Found';
                DefaultUiStates(app)
                return
            end

            spectrometerName = char(app.wrapper.getName(app.spectrometerIndex));
            app.NumOfSpectrometersLabel.Text = [num2str(app.numOfSpectrometers), ' ', ...
                spectrometerName, ' Connected'];
            for i = 1:app.numOfSpectrometers
                index = i - 1;

                % Get spectrometer serial number
                spectrometerSerialNumber = char(app.wrapper.getSerialNumber(index));
                
                if strcmpi(spectrometerSerialNumber, 'Not Found')
                    app.NumOfSpectrometersLabel.Text = 'No Devices Found';
                    DefaultUiStates(app)
                    return
                end

                switch spectrometerSerialNumber
                    case 'QEP03916'
                        spectrometerCH = 'Reference-R';
                        spectrometerSN = spectrometerSerialNumber(end-3:end);
                        spectrometerID = sprintf('%s (%s)', spectrometerCH, spectrometerSN);
                        % as QEP0916 has wavelength range within PAR region
                        app.calcPARIndex = index;
                    case 'QEP03935'
                        spectrometerCH = 'Reference-NIR';
                        spectrometerSN = spectrometerSerialNumber(end-3:end);
                        spectrometerID = sprintf('%s (%s)', spectrometerCH, spectrometerSN);
                    case 'QEP03410'
                        spectrometerCH = 'Sample-R';
                        spectrometerSN = spectrometerSerialNumber(end-3:end);
                        spectrometerID = sprintf('%s (%s)', spectrometerCH, spectrometerSN);
                    case 'QEP03375'
                        spectrometerCH = 'Sample-NIR';
                        spectrometerSN = spectrometerSerialNumber(end-3:end);
                        spectrometerID = sprintf('%s (%s)', spectrometerCH, spectrometerSN);
                end

                switch i
                    case 1
                        app.Spectrometer1CheckBox.Value = 1;
                        app.Spectrometer1CheckBox.Text = spectrometerID;
                        app.SpectrometerTempLabel1.Text = spectrometerCH;
                        app.legendLabel = [app.legendLabel; spectrometerCH];
                        app.SpectrometerTempLabel1.Enable = 'on';
                        app.Gauge1.Enable = 'on';
                        app.GaugeLabel1.Enable = 'on';
                        
                    case 2
                        app.Spectrometer2CheckBox.Value = 1;
                        app.Spectrometer2CheckBox.Text = spectrometerID;
                        app.SpectrometerTempLabel2.Text = spectrometerCH;
                        app.legendLabel = [app.legendLabel; spectrometerCH];
                        app.activeIndex = sort(unique([app.activeIndex, i - 1]));
                        app.SpectrometerTempLabel2.Enable = 'on';
                        app.Gauge2.Enable = 'on';
                        app.GaugeLabel2.Enable = 'on';

                    case 3
                        app.Spectrometer3CheckBox.Value = 1;
                        app.Spectrometer3CheckBox.Text = spectrometerID;
                        app.SpectrometerTempLabel3.Text = spectrometerCH;
                        app.legendLabel = [app.legendLabel; spectrometerCH];
                        app.activeIndex = sort(unique([app.activeIndex, i - 1]));
                        app.SpectrometerTempLabel3.Enable = 'on';
                        app.Gauge3.Enable = 'on';
                        app.GaugeLabel3.Enable = 'on';

                    case 4
                        app.Spectrometer4CheckBox.Value = 1;
                        app.Spectrometer4CheckBox.Text = spectrometerID;
                        app.SpectrometerTempLabel4.Text = spectrometerCH;
                        app.legendLabel = [app.legendLabel; spectrometerCH];
                        app.activeIndex = sort(unique([app.activeIndex, i - 1]));
                        app.SpectrometerTempLabel4.Enable = 'on';
                        app.Gauge4.Enable = 'on';
                        app.GaugeLabel4.Enable = 'on';
                end

                app.SelectSpectrometerDropDown.Items(i) = cellstr(spectrometerCH);
                
                % Enable ElectricalDark and Nonlinearity calibration
                app.wrapper.setCorrectForDetectorNonlinearity(index, 1);
                app.wrapper.setCorrectForElectricalDark(index, 1);
            end
            
            app.isConnected = true;
            app.GeoConfirmButtonPushed()
            app.StartLiveViewButton.Enable = 'on';
            app.MonitorTemperaturesButton.Enable = 'on';
            app.IntegrationTimesEditField.Enable = 'on';
            app.IntegrationTimesEditField.Editable = 'on';
            % app.ConnectSpectrometersButton.Enable = 'off';

        end

        % Button pushed function: StartLiveViewButton
        function StartLiveViewButtonPushed(app, event)
            start(app.timeObj);
            start(app.timeRun);
            app.StartLiveViewButton.Enable = 'off';
            app.StopButton.Enable = 'on';
            app.SetBGButton.Enable = 'on';
            app.NoBGButton.Enable = 'on';
            app.SaveSpectrumButton.Enable = 'on';
            app.SaveRawSpectrumandBGButton.Enable = 'on';
            app.AutomaticButton.Enable = 'on';
            app.SelectSpectrometerDropDown.Enable = 'on';
            app.isRunning = true;
            app.IntegrationTimesEditFieldValueChanged();

            for i = 1:app.numOfSpectrometers
                switch i
                    case 1
                        app.Spectrometer1CheckBox.Enable = 'on';
                    case 2
                        app.Spectrometer2CheckBox.Enable = 'on';
                    case 3
                        app.Spectrometer3CheckBox.Enable = 'on';
                    case 4
                        app.Spectrometer4CheckBox.Enable = 'on';
                end
            end
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.timeObj)
            stop(app.timeRun)
            app.StartLiveViewButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            app.isRunning = false;
            
        end

        % Button pushed function: MonitorTemperaturesButton
        function MonitorTemperaturesButtonPushed(app, event)
            function [temperatureCelsius] = getTemperature(app, spectrometerIndex)
                if app.wrapper.isFeatureSupportedBoardTemperature(spectrometerIndex)
                    boardTemperature = app.wrapper.getFeatureControllerBoardTemperature(spectrometerIndex);
                    temperatureCelsius = boardTemperature.getBoardTemperatureCelsius();
                end
            end
            
            if ~app.MonitorTemperaturesPanel.Visible
                app.MonitorTemperaturesPanel.Visible = 'on';
                for i = 1:app.numOfSpectrometers
                    temperature = getTemperature(app, i - 1);
                    tempMin = 5;
                    tempMax = 45;

                    switch i
                        case 1
                            app.Gauge1.Value = temperature;
                            temperatureStr = sprintf('%.2f°', temperature);
                            app.GaugeLabel1.Text = temperatureStr;
                            if temperature < tempMin || temperature > tempMax
                                app.GaugeLabel1.FontColor = [0.72, 0.19, 0.17];
                            end
                        case 2
                            app.Gauge2.Value = temperature;
                            temperatureStr = sprintf('%.2f°', temperature);
                            app.GaugeLabel2.Text = temperatureStr;
                            if temperature < tempMin || temperature > tempMax
                                app.GaugeLabel2.FontColor = [0.72, 0.19, 0.17];
                            end
                        case 3
                            app.Gauge3.Value = temperature;
                            temperatureStr = sprintf('%.2f°', temperature);
                            app.GaugeLabel3.Text = temperatureStr;
                            if temperature < tempMin || temperature > tempMax
                                app.GaugeLabel3.FontColor = [0.72, 0.19, 0.17];
                            end
                        case 4
                            app.Gauge4.Value = temperature;
                            temperatureStr = sprintf('%.2f°', temperature);
                            app.GaugeLabel4.Text = temperatureStr;
                            if temperature < tempMin || temperature > tempMax
                                app.GaugeLabel4.FontColor = [0.72, 0.19, 0.17];
                            end
                    end
                end
            else
                app.MonitorTemperaturesPanel.Visible = 'off';
            end
            
        end

        % Callback function: ExitButton, UIFigure
        function UIFigureCloseRequest(app, event)
            if app.isRunning
                listOfTimers = timerfindall;
                stop(listOfTimers(:))
                delete(listOfTimers(:))
            end

            if app.isConnected
                app.wrapper.closeAllSpectrometers();
            end

            delete(gcp('nocreate'))
            delete(app)
            
        end

        % Value changed function: AutomaticButton
        function AutomaticButtonValueChanged(app, event)
            i = app.SelectSpectrometerDropDown.Value + 1;
            app.isAuto(i) = app.AutomaticButton.Value;
            
            % Determines optimal integration time by spectral intensity every 3 seconds
            app.scanIndex = 1;
            app.scanMaxValues = zeros(app.advanceSettings.scanToAvg, 1);

            if app.isAuto(i)
                app.integrationTimeOld(i) = app.IntegrationTimesEditField.Value;
            else
                app.IntegrationTimesEditField.Value = app.integrationTimeOld(i);
                app.IntegrationTimesEditFieldValueChanged();
            end
            
        end

        % Value changed function: SelectSpectrometerDropDown
        function SelectSpectrometerDropDownValueChanged(app, event)
            app.spectrometerIndex = app.SelectSpectrometerDropDown.Value;

            i = app.spectrometerIndex + 1;
            app.IntegrationTimesEditField.Value = app.integrationTime(i) / 1000000;
            app.wrapper.setIntegrationTime(app.spectrometerIndex, app.integrationTime(i))
            app.AutomaticButton.Value = app.isAuto(i);
            
        end

        % Value changed function: IntegrationTimesEditField
        function IntegrationTimesEditFieldValueChanged(app, event)
            if app.init
                for i = 1:app.numOfSpectrometers
                    index = i - 1;
                    app.integrationTime(i) = app.IntegrationTimesEditField.Value * 1000000;
                    app.wrapper.setIntegrationTime(index, app.integrationTime(i))
                    app.isAuto(i) = 0;
                end
            else
                app.integrationTime(app.spectrometerIndex + 1) = ...
                    app.IntegrationTimesEditField.Value * 1000000;
                app.wrapper.setIntegrationTime(app.spectrometerIndex, ...
                    app.integrationTime(app.spectrometerIndex + 1))
            end
            
        end

        % Button pushed function: SetBGButton
        function SetBGButtonPushed(app, event)
            for i = 0:app.numOfSpectrometers - 1
                app.bgSpectrum{i + 1} = app.rawSpectrum{i + 1};
            end
            
        end

        % Button pushed function: NoBGButton
        function NoBGButtonPushed(app, event)
            app.bgSpectrum = cell(1, app.numOfSpectrometers);
            for i = 0:app.numOfSpectrometers - 1
                app.bgSpectrum{i + 1} = zeros(size(app.rawSpectrum{i + 1}));
            end
            
        end

        % Button pushed function: SaveSpectrumButton
        function SaveSpectrumButtonPushed(app, event)
            fmts = {'*.mat'; '*.txt'};
            prefix = datestr(now, 'yymmdd_HHMM');
            [file, path, indx] = uiputfile(fmts, 'Save File', [app.defaultSavePath prefix]);

            % User canceled, do nothing
            if isequal(file, 0) || isequal(path, 0)
                figure(app.UIFigure)
                return;
            end

            wavelengthData = single(cell2mat(app.wavelength));
            rawSpectralData = single(cell2mat(app.rawSpectrum));
            bg = single(cell2mat(app.bgSpectrum));
            spectralData = rawSpectralData - bg;

            [infoData, infoPath] = getInfo(app, file, path);

            if indx == 1
                save(fullfile(path, file), 'infoData', 'wavelengthData', 'spectralData');
            elseif indx == 2
                collectedData = {};
                
                for i = 1:numel(app.activeIndex)
                    cellData = [wavelengthData(:, i), rawSpectralData(:, i) - bg(:, i)];
                    collectedData{i} = cellData;
                end
                
                matrixData = cell2mat(collectedData);
                writematrix(matrixData, fullfile(path, file), 'Delimiter', '\t');
                writecell(infoData, infoPath, 'Delimiter', '\t');
            end

            figure(app.UIFigure)

        end

        % Button pushed function: SaveRawSpectrumandBGButton
        function SaveRawSpectrumandBGButtonPushed(app, event)
            fmts = {'*.mat'; '*.txt'};
            prefix = datestr(now, 'yymmdd_HHMM_raw');
            [file, path, indx] = uiputfile(fmts, 'Save File', [app.defaultSavePath prefix]);

            % User canceled, do nothing
            if isequal(file, 0) || isequal(path, 0)
                figure(app.UIFigure)
                return;
            end

            wavelengthData = single(cell2mat(app.wavelength));
            rawSpectralData = single(cell2mat(app.rawSpectrum));
            bg = single(cell2mat(app.bgSpectrum));

            [infoData, infoPath] = getInfo(app, file, path);

            if indx == 1
                save(fullfile(path, file), 'infoData', 'wavelengthData', 'rawSpectralData', 'bg');
            elseif indx == 2
                collectedData = {};
    
                for i = 1:numel(app.activeIndex)
                    cellData = [wavelengthData(:, i), rawSpectralData(:, i), bg(:, i)];
                    collectedData{i} = cellData;
                end
    
                matrixData = cell2mat(collectedData);
                writematrix(matrixData, fullfile(path, file), 'Delimiter', '\t');
                writecell(infoData, infoPath, 'Delimiter', '\t');
            end

            figure(app.UIFigure)
            
        end

        % Button pushed function: SaveFolderButton
        function SaveFolderButtonPushed(app, event)
            winopen(app.defaultSavePath);

        end

        % Value changed function: Spectrometer1CheckBox
        function Spectrometer1CheckBoxValueChanged(app, event)
            showSpectrum = app.Spectrometer1CheckBox.Value;
            index = 0;

            if showSpectrum
                app.activeIndex = sort(unique([app.activeIndex, index]));
                app.wrapper.setIntegrationTime(index, app.integrationTime(index + 1))
                app.wrapper.setCorrectForDetectorNonlinearity(index, 1);
                app.wrapper.setCorrectForElectricalDark(index, 1)
            else
                app.activeIndex = setdiff(app.activeIndex, index);
            end

            % Prevent more than one spectrometer in calibration process
            if app.isCalibrating && numel(app.activeIndex) > 1
                app.Spectrometer1CheckBox.Value = 1;
                app.Spectrometer2CheckBox.Value = 0;
                app.Spectrometer3CheckBox.Value = 0;
                app.Spectrometer4CheckBox.Value = 0;
                app.Spectrometer2CheckBoxValueChanged()
                app.Spectrometer3CheckBoxValueChanged()
                app.Spectrometer4CheckBoxValueChanged()
                app.spectrometerIndex = 0;
                app.SelectSpectrometerDropDown.Value = 0;
                app.SourceGroupSelectionChanged()
            end
            
        end

        % Value changed function: Spectrometer2CheckBox
        function Spectrometer2CheckBoxValueChanged(app, event)
            showSpectrum = app.Spectrometer2CheckBox.Value;
            index = 1;

            if showSpectrum
                app.activeIndex = sort(unique([app.activeIndex, index]));
                app.wrapper.setIntegrationTime(index, app.integrationTime(index + 1))
                app.wrapper.setCorrectForDetectorNonlinearity(index, 1);
                app.wrapper.setCorrectForElectricalDark(index, 1)
            else
                app.activeIndex = setdiff(app.activeIndex, index);
            end

            % Prevent more than one spectrometer in calibration process
            if app.isCalibrating && numel(app.activeIndex) > 1
                app.Spectrometer1CheckBox.Value = 0;
                app.Spectrometer2CheckBox.Value = 1;
                app.Spectrometer3CheckBox.Value = 0;
                app.Spectrometer4CheckBox.Value = 0;
                app.Spectrometer1CheckBoxValueChanged()
                app.Spectrometer3CheckBoxValueChanged()
                app.Spectrometer4CheckBoxValueChanged()
                app.spectrometerIndex = 1;
                app.SelectSpectrometerDropDown.Value = 1;
                app.SourceGroupSelectionChanged()
            end
            
        end

        % Value changed function: Spectrometer3CheckBox
        function Spectrometer3CheckBoxValueChanged(app, event)
            showSpectrum = app.Spectrometer3CheckBox.Value;
            index = 2;

            if showSpectrum
                app.activeIndex = sort(unique([app.activeIndex, index]));
                app.wrapper.setIntegrationTime(index, app.integrationTime(index + 1))
                app.wrapper.setCorrectForDetectorNonlinearity(index, 1);
                app.wrapper.setCorrectForElectricalDark(index, 1)
            else
                app.activeIndex = setdiff(app.activeIndex, index);
            end

            % Prevent more than one spectrometer in calibration process
            if app.isCalibrating && numel(app.activeIndex) > 1
                app.Spectrometer1CheckBox.Value = 0;
                app.Spectrometer2CheckBox.Value = 0;
                app.Spectrometer3CheckBox.Value = 1;
                app.Spectrometer4CheckBox.Value = 0;
                app.Spectrometer1CheckBoxValueChanged()
                app.Spectrometer2CheckBoxValueChanged()
                app.Spectrometer4CheckBoxValueChanged()
                app.spectrometerIndex = 2;
                app.SelectSpectrometerDropDown.Value = 2;
                app.SourceGroupSelectionChanged()
            end
            
        end

        % Value changed function: Spectrometer4CheckBox
        function Spectrometer4CheckBoxValueChanged(app, event)
            showSpectrum = app.Spectrometer4CheckBox.Value;
            index = 3;

            if showSpectrum
                app.activeIndex = sort(unique([app.activeIndex, index]));
                app.wrapper.setIntegrationTime(index, app.integrationTime(index + 1))
                app.wrapper.setCorrectForDetectorNonlinearity(index, 1);
                app.wrapper.setCorrectForElectricalDark(index, 1)
            else
                app.activeIndex = setdiff(app.activeIndex, index);
            end

            % Prevent more than one spectrometer in calibration process
            if app.isCalibrating && numel(app.activeIndex) > 1
                app.Spectrometer1CheckBox.Value = 0;
                app.Spectrometer2CheckBox.Value = 0;
                app.Spectrometer3CheckBox.Value = 0;
                app.Spectrometer4CheckBox.Value = 1;
                app.Spectrometer1CheckBoxValueChanged()
                app.Spectrometer2CheckBoxValueChanged()
                app.Spectrometer3CheckBoxValueChanged()
                app.spectrometerIndex = 3;
                app.SelectSpectrometerDropDown.Value = 3;
                app.SourceGroupSelectionChanged()
            end
            
        end

        % Button pushed function: GeoImportButton
        function GeoImportButtonPushed(app, event)
            path = fullfile(app.defaultSavePath, 'Geographic');
            [file, path] = uigetfile('*.mat', 'Select File', path);

            % User canceled, do nothing
            if isequal(file, 0) || isequal(path, 0)
                figure(app.UIFigure)
                return;
            end

            loadedData = load(fullfile(path, file));
            geoData = loadedData.geoData.data;

            app.LatitudeEditField.Value = geoData.latitude;
            app.LongitudeEditField.Value = geoData.longitude;
            app.ElevationEditField.Value = geoData.elevation;
            app.PressureEditField.Value = geoData.pressure;
            app.TemperatureEditField.Value = geoData.temperature;
            app.SlopeEditField.Value = geoData.slope;
            app.AZMRotationEditField.Value = geoData.azm_rotation;
            app.TimeZoneEditField.Value = geoData.timezone;

            app.GeoConfirmButtonPushed(app)
            figure(app.UIFigure)
            
        end

        % Button pushed function: GeoExportButton
        function GeoExportButtonPushed(app, event)
            data = struct();
            data.latitude = app.LatitudeEditField.Value;
            data.longitude = app.LongitudeEditField.Value;
            data.elevation = app.ElevationEditField.Value;
            data.pressure = app.PressureEditField.Value;
            data.temperature = app.TemperatureEditField.Value;
            data.slope = app.SlopeEditField.Value;
            data.azm_rotation = app.AZMRotationEditField.Value;
            data.timezone = app.TimeZoneEditField.Value;
            
            % Combine header and data into a single structure
            geoData = struct('data', data);
            
            path = fullfile(app.defaultSavePath, 'Geographic');
            if ~isfolder(path)
                mkdir(path)
            end
            [file, path] = uiputfile('*.mat', 'Save File', path);

            % User canceled, do nothing
            if isequal(file, 0) || isequal(path, 0)
                figure(app.UIFigure)
                return;
            end

            save(fullfile(path, file), 'geoData');
            figure(app.UIFigure)

        end

        % Button pushed function: GeoConfirmButton
        function GeoConfirmButtonPushed(app, event)
            latitude = app.LatitudeEditField.Value;
            longitude = app.LongitudeEditField.Value;
            elevation = app.ElevationEditField.Value;
            pressure = app.PressureEditField.Value;
            temperature = app.TemperatureEditField.Value;
            slope = app.SlopeEditField.Value;
            azm_rotation = app.AZMRotationEditField.Value;
            timezone = app.TimeZoneEditField.Value;

            % plus sign check
            if str2double(timezone) > 0 && ~contains(timezone, '+')
                timezone = ['+', timezone];
            end
            app.TimeZoneEditField.Value = timezone;
            
            current_date_time_utc = datetime('now', 'TimeZone', timezone);
            app.doy = day(current_date_time_utc, 'dayofyear');
            timezone = str2double(timezone);
            fmt = 'yyyy-MM-dd-HH-mm-ss';
            str = string(current_date_time_utc, fmt);
            parts = str2double(strsplit(str, '-'));
            year = parts(1);
            month = parts(2);
            day_str = parts(3);
            hour = parts(4);
            minute = parts(5);
            second = parts(6);
            
            [app.zenith, ~, sunrise, sunset] = spa(year, month, day_str, hour, minute, second, timezone, ...
                longitude, latitude, elevation, pressure, temperature, slope, azm_rotation, 0);   
            
            now = string(current_date_time_utc, 'HH:mm:ss');
            sunrise = datenum(sunrise);
            sunset = datenum(sunset);
            now = datenum(now);
            sza_str = sprintf('%.1f°', app.zenith);
            if now > sunrise && now < sunset
                app.SZA.Text = [sza_str, ' / Daytime'];
            else
                app.SZA.Text = [sza_str, ' / Nighttime'];
            end
            

        end

        % Button pushed function: SetToDefaultButton
        function SetToDefaultButtonPushed(app, event)
            app.advanceSettings.scanToAvg = 4;
            app.advanceSettings.maxIT = 8;
            app.advanceSettings.autoITpeak = 0.85;
            app.advanceSettings.prefix = '';
            app.advanceSettings.autoSaveInterval = 300;
            app.advanceSettings.rimShadingAngle = 75;
            
            app.ScanToAverageEditField.Value = app.advanceSettings.scanToAvg;
            app.MaxIntegrationTimeEditField.Value = app.advanceSettings.maxIT;
            app.IntegrationTimesEditField.Limits = [0 app.advanceSettings.maxIT];
            app.AutoIntegrationPeakEditField.Value = app.advanceSettings.autoITpeak;
            app.AutoSavePrefixEditField.Value = app.advanceSettings.prefix;
            app.AutoSaveIntervalEditField.Value = app.advanceSettings.autoSaveInterval;
            app.RimShadingAngleEditField.Value = app.advanceSettings.rimShadingAngle;

        end

        % Value changed function: ScanToAverageEditField
        function ScanToAverageEditFieldValueChanged(app, event)
            app.advanceSettings.scanToAvg = app.ScanToAverageEditField.Value;
            
        end

        % Value changed function: MaxIntegrationTimeEditField
        function MaxIntegrationTimeEditFieldValueChanged(app, event)
            app.advanceSettings.maxIT = app.MaxIntegrationTimeEditField.Value;
            app.IntegrationTimesEditField.Limits = [0 app.advanceSettings.maxIT];
            
        end

        % Value changed function: AutoIntegrationPeakEditField
        function AutoIntegrationPeakEditFieldValueChanged(app, event)
            app.advanceSettings.autoITpeak = app.AutoIntegrationPeakEditField.Value;
            
        end

        % Value changed function: AutoSavePrefixEditField
        function AutoSavePrefixEditFieldValueChanged(app, event)
            app.advanceSettings.prefix = app.AutoSavePrefixEditField.Value;
            
        end

        % Value changed function: AutoSaveIntervalEditField
        function AutoSaveIntervalEditFieldValueChanged(app, event)
            app.advanceSettings.autoSaveInterval = app.AutoSaveIntervalEditField.Value;

        end

        % Value changed function: RimShadingAngleEditField
        function RimShadingAngleEditFieldValueChanged(app, event)
            app.advanceSettings.rimShadingAngle = app.RimShadingAngleEditField.Value;
            
        end

        % Button down function: MainControlsTab
        function MainControlsTabButtonDown(app, event)
            app.isCalibrating = 0;
            app.UIAxes.YLabel.String = 'Intensity (counts)';

        end

        % Button down function: GeographicTab
        function GeographicTabButtonDown(app, event)
            app.isCalibrating = 0;
            app.UIAxes.YLabel.String = 'Intensity (counts)';

        end

        % Button down function: AdvanceTab
        function AdvanceTabButtonDown(app, event)
            app.isCalibrating = 0;
            app.UIAxes.YLabel.String = 'Intensity (counts)';
            
        end

        % Button down function: CalibrationTab
        function CalibrationTabButtonDown(app, event)
            app.isCalibrating = 1;
            app.SourceGroupSelectionChanged()

            % init
            app.finishedCalSpec = [0, 0, 0, 0];
            app.IntegrationTimesEditField.Value = 0.1;
            app.IntegrationTimesEditFieldValueChanged()
            app.StoreReferenceButton.Value = 0;
            app.StoreBackgroundButton.Value = 0;

            % Increase limits to avoid bugs
            % app.MaxIntegrationTimeEditField.Value = 2;
            % app.MaxIntegrationTimeEditFieldValueChanged()

            % Keep only one spectrometer to do the calibration
            if numel(app.activeIndex) > 1
                app.spectrometerIndex = app.activeIndex(1);
                app.Spectrometer1CheckBox.Value = 0;
                app.Spectrometer2CheckBox.Value = 0;
                app.Spectrometer3CheckBox.Value = 0;
                app.Spectrometer4CheckBox.Value = 0;

                switch app.spectrometerIndex
                    case 0
                        app.Spectrometer1CheckBox.Value = 1;
                    case 1
                        app.Spectrometer2CheckBox.Value = 1;
                    case 2
                        app.Spectrometer3CheckBox.Value = 1;
                    case 3
                        app.Spectrometer4CheckBox.Value = 1;
                end

                app.Spectrometer1CheckBoxValueChanged()
                app.Spectrometer2CheckBoxValueChanged()
                app.Spectrometer3CheckBoxValueChanged()
                app.Spectrometer4CheckBoxValueChanged()
            end

        end

        % Selection changed function: SourceGroup
        function SourceGroupSelectionChanged(app, event)
            if strcmp(app.timeObj.Running, 'off')
                start(app.timeObj)
                start(app.timeRun)
                app.isRunning = true;
            end
            
            app.absoluteIrradiance.calibrationMode = get(app.SourceGroup.SelectedObject, 'Text');

            % Acting like initialization
            i = app.spectrometerIndex + 1;
            app.absoluteIrradiance.finishedCalFile = [];
            app.absoluteIrradiance.lambda = [];
            app.absoluteIrradiance.reference = [];
            app.absoluteIrradiance.background = [];
            app.absoluteIrradiance.calFile = [];

            app.isAuto(i) = 0;
            if app.integrationTimeOld
                app.IntegrationTimesEditField.Value = app.integrationTimeOld(i);
                app.IntegrationTimesEditFieldValueChanged();
            end

            if strcmpi(app.absoluteIrradiance.calibrationMode, 'New Calibration')
                app.StoreReferenceButton.Enable = 'on';
                app.StoreBackgroundButton.Enable = 'on';
                app.BrowseButton.Enable = 'on';
                app.LoadFromFileButton.Enable = 'off';
                app.SaveToFileButton.Enable = 'off';
                app.FinishButton.Enable = 'off';
            else
                app.LoadFromFileButton.Enable = 'on';
                app.StoreReferenceButton.Enable = 'off';
                app.StoreReferenceButton.Value = 0;
                app.StoreBackgroundButton.Enable = 'off';
                app.StoreBackgroundButton.Value = 0;
                app.BrowseButton.Enable = 'off';
                app.SaveToFileButton.Enable = 'off';
                app.FinishButton.Enable = 'off';
            end
            
        end

        % Value changed function: StoreReferenceButton
        function StoreReferenceButtonValueChanged(app, event)
            i = app.spectrometerIndex + 1;

            if app.StoreReferenceButton.Value
                if strcmp(app.timeObj.Running, 'off')
                    start(app.timeObj)
                    start(app.timeRun)
                    app.isRunning = true;
                    pause(2)
                end

                % Auto integration
                app.isAuto(i) = app.StoreReferenceButton.Value;
                app.scanIndex = 1;
                app.scanMaxValues = zeros(app.advanceSettings.scanToAvg, 1);
                app.integrationTimeOld(i) = app.IntegrationTimesEditField.Value;

                % Take some time to collect light
                pause(app.advanceSettings.scanToAvg)
                pause(app.IntegrationTimesEditField.Value + 0.1)

                % Find the non-empty cell and extract its contents
                nonEmptyCell = app.wavelength(~cellfun('isempty', app.wavelength));
                resultingArray = nonEmptyCell{1};
                app.absoluteIrradiance.lambda = resultingArray;

                nonEmptyCell = app.rawSpectrum(~cellfun('isempty', app.rawSpectrum));
                resultingArray = nonEmptyCell{1};
                app.absoluteIrradiance.reference = resultingArray;

                stop(app.timeObj)
                stop(app.timeRun)
                app.isRunning = false;
            else
                if strcmp(app.timeObj.Running, 'on')
                    stop(app.timeObj)
                    stop(app.timeRun)
                    app.isRunning = false;
                    pause(2)
                end

                app.IntegrationTimesEditField.Value = app.integrationTimeOld(i);
                app.IntegrationTimesEditFieldValueChanged();

                app.absoluteIrradiance.lambda = [];
                app.absoluteIrradiance.reference = [];

                start(app.timeObj)
                start(app.timeRun)
                app.isRunning = true;
            end

            app.isAuto(i) = 0;
            if ~isempty(app.absoluteIrradiance.reference) && ~isempty(app.absoluteIrradiance.background)
                app.SaveToFileButton.Enable = 'on';
            else
                app.SaveToFileButton.Enable = 'off';
            end

        end

        % Value changed function: StoreBackgroundButton
        function StoreBackgroundButtonValueChanged(app, event)
            if app.StoreBackgroundButton.Value
                if strcmp(app.timeObj.Running, 'off')
                    start(app.timeObj)
                    start(app.timeRun)
                    app.isRunning = true;
                    pause(2)
                end

                nonEmptyCell = app.rawSpectrum(~cellfun('isempty', app.rawSpectrum));
                resultingArray = nonEmptyCell{1};
                app.absoluteIrradiance.background = resultingArray;

                stop(app.timeObj)
                stop(app.timeRun)
                app.isRunning = false;
            else
                if strcmp(app.timeObj.Running, 'on')
                    stop(app.timeObj)
                    stop(app.timeRun)
                    app.isRunning = false;
                    pause(2)
                end

                app.absoluteIrradiance.background = [];

                start(app.timeObj)
                start(app.timeRun)
                app.isRunning = true;
            end

            if ~isempty(app.absoluteIrradiance.reference) && ~isempty(app.absoluteIrradiance.background)
                app.SaveToFileButton.Enable = 'on';
            else
                app.SaveToFileButton.Enable = 'off';
            end

        end

        % Button pushed function: BrowseButton
        function BrowseButtonValueChanged(app, event)
            path = fullfile('./src/');
            [file, path] = uigetfile('*.lmp', 'Select File', path);

            % User canceled, do nothing
            if isequal(file, 0) || isequal(path, 0)
                figure(app.UIFigure)
                return;
            end

            app.LampFileName.Text = file;

            figure(app.UIFigure)
            
        end

        % Value changed function: FiberDiameterEditField
        function FiberDiameterEditFieldValueChanged(app, event)
            app.absoluteIrradiance.fiberDiameter = app.FiberDiameterEditField.Value;

        end

        % Button pushed function: LoadFromFileButton
        function LoadFromFileButtonPushed(app, event)
            path = fullfile(app.defaultSavePath, 'Calibration');
            [file, path] = uigetfile('*.mat', 'Select File', path);

            % User canceled, do nothing
            if isequal(file, 0) || isequal(path, 0)
                figure(app.UIFigure)
                return;
            end

            loadedData = load(fullfile(path, file));
            calData = loadedData.IpCalData(:, 1);

            app.scanMaxValues = zeros(app.advanceSettings.scanToAvg, 1);
            % Measurement parameters can be different to when made the calibration
            for k = 1:app.advanceSettings.scanToAvg
                app.scanMaxValues(k) = max(app.rawSpectrum{app.spectrometerIndex + 1});
            end

            maxIntensity = app.wrapper.getMaximumIntensity(app.spectrometerIndex);
            safeIntensity = maxIntensity * app.advanceSettings.autoITpeak;
            integrationTimeRatio = mean(app.scanMaxValues) / safeIntensity;
            app.absoluteIrradiance.T = app.IntegrationTimesEditField.Value / integrationTimeRatio;

            if app.absoluteIrradiance.T > app.advanceSettings.maxIT
                app.IntegrationTimesEditField.Value = app.advanceSettings.maxIT;
            else
                app.IntegrationTimesEditField.Value = str2double(sprintf('%.3f', app.absoluteIrradiance.T));
            end
            app.IntegrationTimesEditFieldValueChanged()

            app.FiberDiameterEditField.Value = str2double(loadedData.infoData{4, 2});
            lambda = loadedData.wavelengthData(:, 1);
            app.absoluteIrradiance.dLp = (lambda(end) - lambda(1))/length(lambda);

            app.absoluteIrradiance.calFile = calData;
            app.FinishButton.Enable = 'on';

            figure(app.UIFigure)

        end

        % Button pushed function: SaveToFileButton
        function SaveToFileButtonPushed(app, event)
            % Generate calibration file (μJ/count)
            reference = app.absoluteIrradiance.reference;
            background = app.absoluteIrradiance.background;
            IpRaw = reference - background;

            app.absoluteIrradiance.T = app.IntegrationTimesEditField.Value; % integrationt time (s)
            d = app.absoluteIrradiance.fiberDiameter;                       % effective core diameter (μm)
            A = pi*(d*0.0001/2)^2;                                          % collection area (cm^2)
            lambda = app.absoluteIrradiance.lambda;
            app.absoluteIrradiance.dLp = (lambda(end) - lambda(1))/length(lambda);
            
            fileDir = './src/';
            fileNameBfw = app.LampFileName.Text;
            [wlBfw, intBfw] = ReadTxtFiles(fileDir, fileNameBfw, [600 900]);
            intBfw = interp1(wlBfw, intBfw, lambda, 'spline');

            app.absoluteIrradiance.calFile = intBfw ./ IpRaw * ...
                (app.absoluteIrradiance.T * A * app.absoluteIrradiance.dLp);
            
            wavelengthData = single(lambda);
            IpCalData = single(app.absoluteIrradiance.calFile);

            [infoData, ~] = getInfo(app, false, false);

            % Save calibration file
            path = fullfile(app.defaultSavePath, 'Calibration');
            if ~isfolder(path)
                mkdir(path)
            end

            i = app.spectrometerIndex + 1;
            splitStr = strsplit(app.(['Spectrometer' num2str(i) 'CheckBox']).Text, ' ');
            spectrometerSN = sprintf('QEP0%s', splitStr{2}(2:end-1));

            [file, path] = uiputfile('*.mat', 'Save File', fullfile(path, ['HLVISCAL_' spectrometerSN]));

            % User canceled, do nothing
            if isequal(file, 0) || isequal(path, 0)
                figure(app.UIFigure)
                return;
            end

            save(fullfile(path, file), 'infoData', 'wavelengthData', 'IpCalData');
            app.FinishButton.Enable = 'on';

            figure(app.UIFigure)

        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            d = app.absoluteIrradiance.fiberDiameter;
            A = pi*(d*0.0001/2)^2;
            
            i = app.spectrometerIndex + 1;
            app.finishedCalSpec(i) = 1;
            app.absoluteIrradiance.finishedCalFile{i} = app.absoluteIrradiance.calFile / ...
                (app.absoluteIrradiance.T * A * app.absoluteIrradiance.dLp);

            app.StoreReferenceButton.Value = 0;
            app.StoreBackgroundButton.Value = 0;
            app.FinishButton.Enable = 'off';
            app.UIAxes.YLabel.String = 'Absolute Irradiance (µW/cm^2/nm)';

            % Auto save spectral data
            wavelengthData = single(cell2mat(app.wavelength));
            data = app.rawSpectrum{i} .* app.absoluteIrradiance.finishedCalFile{i};
            rawSpectralData = single(data);
            
            [infoData, ~] = getInfo(app, false, false);

            path = fullfile(app.defaultSavePath, 'Calibration', 'CalScreenshot');
            
            if ~isfolder(path)
                mkdir(path)
            end

            splitStr = strsplit(app.(['Spectrometer' num2str(i) 'CheckBox']).Text, ' ');
            spectrometerSN = sprintf('QEP0%s', splitStr{2}(2:end-1));
            
            file = sprintf('%s.mat', [datestr(now, 'yymmdd_HHMM_') spectrometerSN]);
            
            save(fullfile(path, file), 'infoData', 'wavelengthData', 'rawSpectralData');

            if strcmp(app.timeObj.Running, 'off')
                start(app.timeObj)
                start(app.timeRun)
                app.isRunning = true;
                pause(2)
            end

        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.SourceGroupSelectionChanged()
            app.StoreReferenceButton.Value = 0;
            app.StoreBackgroundButton.Value = 0;
            app.IntegrationTimesEditField.Value = 0.1;
            app.IntegrationTimesEditFieldValueChanged()
            app.UIAxes.YLabel.String = 'Intensity (counts)';

            i = app.spectrometerIndex + 1;
            app.finishedCalSpec(i) = 0;
            app.absoluteIrradiance.finishedCalFile{i} = [];

            if strcmp(app.timeObj.Running, 'off')
                start(app.timeObj)
                start(app.timeRun)
                app.isRunning = true;
                pause(2)
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {534, 534, 534};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {534, 534};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {269, '1x', 287};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanelGrid = uigridlayout(app.LeftPanel);
            app.ConnectSpectrometersButton = uibutton(app.LeftPanelGrid, 'push');
            app.ConnectSpectrometersButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectSpectrometersButtonPushed, true);
            app.ConnectSpectrometersButton.Text = 'Connect Spectrometer';
            app.NumOfSpectrometersLabel = uilabel(app.LeftPanelGrid);
            app.NumOfSpectrometersLabel.Text = '';
            app.RunTimeLabel = uilabel(app.LeftPanelGrid);
            app.RunTimeLabel.Tooltip = {'(HH:MM:SS)'};
            app.RunTimeLabel.Text = 'Run Time';
            app.RunTime = uilabel(app.LeftPanelGrid);
            app.RunTime.Text = '00:00:00';
            app.StartLiveViewButton = uibutton(app.LeftPanelGrid, 'push');
            app.StartLiveViewButton.ButtonPushedFcn = createCallbackFcn(app, @StartLiveViewButtonPushed, true);
            app.StartLiveViewButton.Enable = 'off';
            app.StartLiveViewButton.Text = 'Start Live View';
            app.StopButton = uibutton(app.LeftPanelGrid, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.MonitorTemperaturesButton = uibutton(app.LeftPanelGrid, 'push');
            app.MonitorTemperaturesButton.ButtonPushedFcn = createCallbackFcn(app, @MonitorTemperaturesButtonPushed, true);
            app.MonitorTemperaturesButton.Enable = 'off';
            app.MonitorTemperaturesButton.Text = 'Monitor Temperatures';
            app.SZADiurnalLabel = uilabel(app.LeftPanelGrid);
            app.SZADiurnalLabel.Tooltip = {'(degree)'};
            app.SZADiurnalLabel.Text = 'SZA / Diurnal';
            app.SZA = uilabel(app.LeftPanelGrid);
            app.SZA.Text = 'Null';
            app.CICVLabel = uilabel(app.LeftPanelGrid);
            app.CICVLabel.Tooltip = {'(µmol / m^2 / s)'};
            app.CICVLabel.Text = 'CI / CV';
            app.CICV = uilabel(app.LeftPanelGrid);
            app.CICV.Text = 'Null';
            app.SkyConditionLabel = uilabel(app.LeftPanelGrid);
            app.SkyConditionLabel.Tooltip = {'(mW / m^2 / sr / nm)'};
            app.SkyConditionLabel.Text = 'Sky Condition';
            app.SkyCondition = uilabel(app.LeftPanelGrid);
            app.SkyCondition.Text = 'Null';
            app.SIFLabel = uilabel(app.LeftPanelGrid);
            app.SIFLabel.Tooltip = {'(mW / m^2 / sr / nm)'};
            app.SIFLabel.Text = 'SIF';
            app.SIF = uilabel(app.LeftPanelGrid);
            app.SIF.Text = 'Null';
            app.ExitButton = uibutton(app.LeftPanelGrid, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitButton.Text = 'Exit';
            app.CenterPanel = uipanel(app.GridLayout);
            app.PlotGrid = uigridlayout(app.CenterPanel);
            app.CenterGrid = uigridlayout(app.PlotGrid);
            app.UIAxes = uiaxes(app.CenterGrid);
            xlabel(app.UIAxes, 'Wavelength (nm)')
            ylabel(app.UIAxes, 'Intensity (counts)')
            app.UIAxes.ZTick = [];
            app.UIAxes.ZTickLabel = '';
            app.MonitorTemperaturesPanel = uipanel(app.CenterGrid);
            app.MonitorTemperaturesPanel.Title = 'Monitor Temperatures';
            app.MonitorTemperaturesPanel.Visible = 'off';
            app.TemperaturePanelGrid = uigridlayout(app.MonitorTemperaturesPanel);
            app.SpectrometerTempLabel1 = uilabel(app.TemperaturePanelGrid);
            app.SpectrometerTempLabel1.Enable = 'off';
            app.SpectrometerTempLabel1.Text = 'Spectrometer1';
            app.SpectrometerTempLabel2 = uilabel(app.TemperaturePanelGrid);
            app.SpectrometerTempLabel2.Enable = 'off';
            app.SpectrometerTempLabel2.Text = 'Spectrometer2';
            app.SpectrometerTempLabel3 = uilabel(app.TemperaturePanelGrid);
            app.SpectrometerTempLabel3.Enable = 'off';
            app.SpectrometerTempLabel3.Text = 'Spectrometer3';
            app.SpectrometerTempLabel4 = uilabel(app.TemperaturePanelGrid);
            app.SpectrometerTempLabel4.Enable = 'off';
            app.SpectrometerTempLabel4.Text = 'Spectrometer4';
            app.GaugeGrid1 = uigridlayout(app.TemperaturePanelGrid);
            app.Gauge1 = uigauge(app.GaugeGrid1, 'semicircular');
            app.Gauge1.Limits = [0 50];
            app.Gauge1.Enable = 'off';
            app.GaugeLabel1 = uilabel(app.GaugeGrid1);
            app.GaugeLabel1.Enable = 'off';
            app.GaugeLabel1.Text = 'NaN';
            app.GaugeGrid2 = uigridlayout(app.TemperaturePanelGrid);
            app.Gauge2 = uigauge(app.GaugeGrid2, 'semicircular');
            app.Gauge2.Limits = [0 50];
            app.Gauge2.Enable = 'off';
            app.GaugeLabel2 = uilabel(app.GaugeGrid2);
            app.GaugeLabel2.Enable = 'off';
            app.GaugeLabel2.Text = 'NaN';
            app.GaugeGrid3 = uigridlayout(app.TemperaturePanelGrid);
            app.Gauge3 = uigauge(app.GaugeGrid3, 'semicircular');
            app.Gauge3.Limits = [0 50];
            app.Gauge3.Enable = 'off';
            app.GaugeLabel3 = uilabel(app.GaugeGrid3);
            app.GaugeLabel3.Enable = 'off';
            app.GaugeLabel3.Text = 'NaN';
            app.GaugeGrid4 = uigridlayout(app.TemperaturePanelGrid);
            app.Gauge4 = uigauge(app.GaugeGrid4, 'semicircular');
            app.Gauge4.Limits = [0 50];
            app.Gauge4.Enable = 'off';
            app.GaugeLabel4 = uilabel(app.GaugeGrid4);
            app.GaugeLabel4.Enable = 'off';
            app.GaugeLabel4.Text = 'NaN';
            app.SpectrometersGrid = uigridlayout(app.CenterGrid);
            app.Spectrometer1CheckBox = uicheckbox(app.SpectrometersGrid);
            app.Spectrometer1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Spectrometer1CheckBoxValueChanged, true);
            app.Spectrometer1CheckBox.Enable = 'off';
            app.Spectrometer1CheckBox.Text = 'Spectrometer1';
            app.Spectrometer2CheckBox = uicheckbox(app.SpectrometersGrid);
            app.Spectrometer2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Spectrometer2CheckBoxValueChanged, true);
            app.Spectrometer2CheckBox.Enable = 'off';
            app.Spectrometer2CheckBox.Text = 'Spectrometer2';
            app.Spectrometer3CheckBox = uicheckbox(app.SpectrometersGrid);
            app.Spectrometer3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Spectrometer3CheckBoxValueChanged, true);
            app.Spectrometer3CheckBox.Enable = 'off';
            app.Spectrometer3CheckBox.Text = 'Spectrometer3';
            app.Spectrometer4CheckBox = uicheckbox(app.SpectrometersGrid);
            app.Spectrometer4CheckBox.ValueChangedFcn = createCallbackFcn(app, @Spectrometer4CheckBoxValueChanged, true);
            app.Spectrometer4CheckBox.Enable = 'off';
            app.Spectrometer4CheckBox.Text = 'Spectrometer4';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroupGrid = uigridlayout(app.RightPanel);
            app.TabGroup = uitabgroup(app.TabGroupGrid);
            app.MainControlsTab = uitab(app.TabGroup);
            app.MainControlsTab.Title = 'Main Controls';
            app.MainControlsTab.ButtonDownFcn = createCallbackFcn(app, @MainControlsTabButtonDown, true);
            app.MainGrid = uigridlayout(app.MainControlsTab);
            app.IntegrationTimesEditFieldLabel = uilabel(app.MainGrid);
            app.IntegrationTimesEditFieldLabel.Tooltip = {'(s)'};
            app.IntegrationTimesEditFieldLabel.Text = 'Integration Time';
            app.AutomaticButton = uibutton(app.MainGrid, 'state');
            app.AutomaticButton.ValueChangedFcn = createCallbackFcn(app, @AutomaticButtonValueChanged, true);
            app.AutomaticButton.Enable = 'off';
            app.AutomaticButton.Tooltip = {'Sets integration time to recommended peak value.'; 'Click Automatic to Start/Stop autoset process.'};
            app.AutomaticButton.Text = 'Automatic';
            app.SelectSpectrometerDropDown = uidropdown(app.MainGrid);
            app.SelectSpectrometerDropDown.Items = {};
            app.SelectSpectrometerDropDown.ItemsData = [0 1 2 3];
            app.SelectSpectrometerDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectSpectrometerDropDownValueChanged, true);
            app.SelectSpectrometerDropDown.Enable = 'off';
            app.SelectSpectrometerDropDown.Tooltip = {'Select different device to change the integration time.'};
            app.SelectSpectrometerDropDown.Placeholder = 'N/A';
            app.SelectSpectrometerDropDown.Value = {};
            app.IntegrationTimesEditField = uieditfield(app.MainGrid, 'numeric');
            app.IntegrationTimesEditField.Limits = [0.008 8];
            app.IntegrationTimesEditField.ValueChangedFcn = createCallbackFcn(app, @IntegrationTimesEditFieldValueChanged, true);
            app.IntegrationTimesEditField.Editable = 'off';
            app.IntegrationTimesEditField.Enable = 'off';
            app.IntegrationTimesEditField.Tooltip = {'Max: 8s (default)'; 'Min: 0.008s'; 'Default: 0.1s'};
            app.IntegrationTimesEditField.Value = 0.1;
            app.SetBGButton = uibutton(app.MainGrid, 'push');
            app.SetBGButton.ButtonPushedFcn = createCallbackFcn(app, @SetBGButtonPushed, true);
            app.SetBGButton.Enable = 'off';
            app.SetBGButton.Tooltip = {'Subtract background and set current reading to zero.'};
            app.SetBGButton.Text = 'Set BG';
            app.NoBGButton = uibutton(app.MainGrid, 'push');
            app.NoBGButton.ButtonPushedFcn = createCallbackFcn(app, @NoBGButtonPushed, true);
            app.NoBGButton.Enable = 'off';
            app.NoBGButton.Tooltip = {'Remove background value'};
            app.NoBGButton.Text = 'No BG';
            app.ShowApparentReflectanceButton = uibutton(app.MainGrid, 'push');
            app.ShowApparentReflectanceButton.Enable = 'off';
            app.ShowApparentReflectanceButton.Tooltip = {'Subtract background and set current reading to zero.'};
            app.ShowApparentReflectanceButton.Text = 'Show Apparent Reflectance';
            app.GridLayout2 = uigridlayout(app.MainGrid);
            app.SaveSpectrumButton = uibutton(app.GridLayout2, 'push');
            app.SaveSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSpectrumButtonPushed, true);
            app.SaveSpectrumButton.Enable = 'off';
            app.SaveSpectrumButton.Text = 'Save Spectrum';
            app.SaveFolderButton = uibutton(app.GridLayout2, 'push');
            app.SaveFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFolderButtonPushed, true);
            app.SaveFolderButton.Icon = fullfile(pathToMLAPP, 'src', 'folder_icon.png');
            app.SaveFolderButton.Text = '';
            app.SaveRawSpectrumandBGButton = uibutton(app.MainGrid, 'push');
            app.SaveRawSpectrumandBGButton.ButtonPushedFcn = createCallbackFcn(app, @SaveRawSpectrumandBGButtonPushed, true);
            app.SaveRawSpectrumandBGButton.Enable = 'off';
            app.SaveRawSpectrumandBGButton.Text = 'Save Raw Spectrum, and BG';
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.CalibrationTab.ButtonDownFcn = createCallbackFcn(app, @CalibrationTabButtonDown, true);
            app.CalibrationGrid = uigridlayout(app.CalibrationTab);
            app.SourceGroup = uibuttongroup(app.CalibrationGrid);
            app.SourceGroup.SelectionChangedFcn = createCallbackFcn(app, @SourceGroupSelectionChanged, true);
            app.GetIrradianceCalibrationfromFileButton = uiradiobutton(app.SourceGroup);
            app.GetIrradianceCalibrationfromFileButton.Text = 'Get Irradiance Calibration from File';
            app.GetIrradianceCalibrationfromFileButton.Value = true;
            app.NewCalibrationButton = uiradiobutton(app.SourceGroup);
            app.NewCalibrationButton.Text = 'New Calibration';
            app.StoreReferenceButton = uibutton(app.CalibrationGrid, 'state');
            app.StoreReferenceButton.ValueChangedFcn = createCallbackFcn(app, @StoreReferenceButtonValueChanged, true);
            app.StoreReferenceButton.Enable = 'off';
            app.StoreReferenceButton.Tooltip = {'Once the lamp is ready, press the button above to store a reference spectrum.'};
            app.StoreReferenceButton.Text = 'Store Reference';
            app.StoreBackgroundButton = uibutton(app.CalibrationGrid, 'state');
            app.StoreBackgroundButton.ValueChangedFcn = createCallbackFcn(app, @StoreBackgroundButtonValueChanged, true);
            app.StoreBackgroundButton.Enable = 'off';
            app.StoreBackgroundButton.Tooltip = {'We want to eliminate as much light as possible and gather a background capture.'; 'Block the light or uncheck strobe/lamp enable and press the button above to store a spectrum.'};
            app.StoreBackgroundButton.Text = 'Store Background';
            app.LampFileLabel = uilabel(app.CalibrationGrid);
            app.LampFileLabel.Tooltip = {''};
            app.LampFileLabel.Text = 'Lamp file';
            app.BrowseButton = uibutton(app.CalibrationGrid, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonValueChanged, true);
            app.BrowseButton.Enable = 'off';
            app.BrowseButton.Text = 'Browse';
            app.LampFileName = uilabel(app.CalibrationGrid);
            app.LampFileName.Tooltip = {''};
            app.LampFileName.Text = '7003P1192_HL-3 plus-CAL_bfw_20230720_VIS.lmp';
            app.FiberDiameterEditField_2Label = uilabel(app.CalibrationGrid);
            app.FiberDiameterEditField_2Label.Tooltip = {'The spectrometer analyzes the number of photons received into the spectrometer.'; 'The area should use the Fiber core size or the area of ​​the Cosine corrector.'};
            app.FiberDiameterEditField_2Label.Text = 'Fiber Diameter';
            app.FiberDiameterEditField = uieditfield(app.CalibrationGrid, 'numeric');
            app.FiberDiameterEditField.Limits = [0 Inf];
            app.FiberDiameterEditField.ValueDisplayFormat = '%.0f';
            app.FiberDiameterEditField.ValueChangedFcn = createCallbackFcn(app, @FiberDiameterEditFieldValueChanged, true);
            app.FiberDiameterEditField.Tooltip = {'Default: 355 (micron) round end effective core diameter'};
            app.FiberDiameterEditField.Value = 355;
            app.LoadFromFileButton = uibutton(app.CalibrationGrid, 'push');
            app.LoadFromFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFromFileButtonPushed, true);
            app.LoadFromFileButton.Enable = 'off';
            app.LoadFromFileButton.Text = 'Load From File';
            app.SaveToFileButton = uibutton(app.CalibrationGrid, 'push');
            app.SaveToFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveToFileButtonPushed, true);
            app.SaveToFileButton.Enable = 'off';
            app.SaveToFileButton.Text = 'Save To File';
            app.FinishButton = uibutton(app.CalibrationGrid, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Enable = 'off';
            app.FinishButton.Text = 'Finish';
            app.ClearButton = uibutton(app.CalibrationGrid, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.GeographicTab = uitab(app.TabGroup);
            app.GeographicTab.Title = 'Geographic';
            app.GeographicTab.ButtonDownFcn = createCallbackFcn(app, @GeographicTabButtonDown, true);
            app.GeoGrid = uigridlayout(app.GeographicTab);
            app.LatitudeEditFieldLabel = uilabel(app.GeoGrid);
            app.LatitudeEditFieldLabel.Tooltip = {'(degree)'};
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.LatitudeEditField = uieditfield(app.GeoGrid, 'numeric');
            app.LatitudeEditField.Limits = [-90 90];
            app.LatitudeEditField.ValueDisplayFormat = '%11.8g';
            app.LatitudeEditField.Tooltip = {''};
            app.LatitudeEditField.Value = 23.5084;
            app.LongitudeEditFieldLabel = uilabel(app.GeoGrid);
            app.LongitudeEditFieldLabel.Tooltip = {'(degree)'};
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.LongitudeEditField = uieditfield(app.GeoGrid, 'numeric');
            app.LongitudeEditField.Limits = [-180 180];
            app.LongitudeEditField.ValueDisplayFormat = '%11.8g';
            app.LongitudeEditField.Tooltip = {''};
            app.LongitudeEditField.Value = 120.8108;
            app.ElevationEditFieldLabel = uilabel(app.GeoGrid);
            app.ElevationEditFieldLabel.Tooltip = {'(m)'};
            app.ElevationEditFieldLabel.Text = 'Elevation';
            app.ElevationEditField = uieditfield(app.GeoGrid, 'numeric');
            app.ElevationEditField.ValueDisplayFormat = '%11.8g';
            app.ElevationEditField.Tooltip = {''};
            app.ElevationEditField.Value = 2413.4;
            app.PressureEditFieldLabel = uilabel(app.GeoGrid);
            app.PressureEditFieldLabel.Tooltip = {'(millibars)'};
            app.PressureEditFieldLabel.Text = 'Pressure';
            app.PressureEditField = uieditfield(app.GeoGrid, 'numeric');
            app.PressureEditField.ValueDisplayFormat = '%11.8g';
            app.PressureEditField.Tooltip = {''};
            app.PressureEditField.Value = 1014;
            app.TemperatureEditFieldLabel = uilabel(app.GeoGrid);
            app.TemperatureEditFieldLabel.Tooltip = {'(celsius)'};
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.GeoGrid, 'numeric');
            app.TemperatureEditField.ValueDisplayFormat = '%11.8g';
            app.TemperatureEditField.Tooltip = {''};
            app.TemperatureEditField.Value = 25;
            app.SlopeEditFieldLabel = uilabel(app.GeoGrid);
            app.SlopeEditFieldLabel.Tooltip = {'(degree)'};
            app.SlopeEditFieldLabel.Text = 'Slope';
            app.SlopeEditField = uieditfield(app.GeoGrid, 'numeric');
            app.SlopeEditField.ValueDisplayFormat = '%11.8g';
            app.SlopeEditField.Tooltip = {''};
            app.AZMRotationEditFieldLabel = uilabel(app.GeoGrid);
            app.AZMRotationEditFieldLabel.Tooltip = {'(degree)'};
            app.AZMRotationEditFieldLabel.Text = 'AZM Rotation';
            app.AZMRotationEditField = uieditfield(app.GeoGrid, 'numeric');
            app.AZMRotationEditField.ValueDisplayFormat = '%11.8g';
            app.AZMRotationEditField.Tooltip = {''};
            app.TimeZoneEditFieldLabel = uilabel(app.GeoGrid);
            app.TimeZoneEditFieldLabel.Tooltip = {'(UTC)'};
            app.TimeZoneEditFieldLabel.Text = 'Time Zone';
            app.TimeZoneEditField = uieditfield(app.GeoGrid, 'text');
            app.TimeZoneEditField.Tooltip = {''};
            app.TimeZoneEditField.Value = '+8';
            app.GeoImportButton = uibutton(app.GeoGrid, 'push');
            app.GeoImportButton.ButtonPushedFcn = createCallbackFcn(app, @GeoImportButtonPushed, true);
            app.GeoImportButton.Text = 'Import';
            app.GeoExportButton = uibutton(app.GeoGrid, 'push');
            app.GeoExportButton.ButtonPushedFcn = createCallbackFcn(app, @GeoExportButtonPushed, true);
            app.GeoExportButton.Text = 'Export';
            app.GeoConfirmButton = uibutton(app.GeoGrid, 'push');
            app.GeoConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @GeoConfirmButtonPushed, true);
            app.GeoConfirmButton.Text = 'Confirm';
            app.AdvanceTab = uitab(app.TabGroup);
            app.AdvanceTab.Title = 'Advance';
            app.AdvanceTab.ButtonDownFcn = createCallbackFcn(app, @AdvanceTabButtonDown, true);
            app.AdvanceGrid = uigridlayout(app.AdvanceTab);
            app.MaxIntegrationTimeEditFieldLabel = uilabel(app.AdvanceGrid);
            app.MaxIntegrationTimeEditFieldLabel.Tooltip = {'Default: 8s'};
            app.MaxIntegrationTimeEditFieldLabel.Text = 'Max Integration Time';
            app.MaxIntegrationTimeEditField = uieditfield(app.AdvanceGrid, 'numeric');
            app.MaxIntegrationTimeEditField.Limits = [1 3600];
            app.MaxIntegrationTimeEditField.ValueChangedFcn = createCallbackFcn(app, @MaxIntegrationTimeEditFieldValueChanged, true);
            app.MaxIntegrationTimeEditField.Tooltip = {''};
            app.MaxIntegrationTimeEditField.Value = 8;
            app.AutoIntegrationAvgLabel = uilabel(app.AdvanceGrid);
            app.AutoIntegrationAvgLabel.Tooltip = {'The number of discrete spectral acquisitions that the device driver accumulates before receives a spectrum.'; 'Default: 4'};
            app.AutoIntegrationAvgLabel.Text = 'Scan To Average';
            app.ScanToAverageEditField = uieditfield(app.AdvanceGrid, 'numeric');
            app.ScanToAverageEditField.Limits = [1 8];
            app.ScanToAverageEditField.ValueChangedFcn = createCallbackFcn(app, @ScanToAverageEditFieldValueChanged, true);
            app.ScanToAverageEditField.Value = 4;
            app.AutoIntegrationPeakEditFieldLabel = uilabel(app.AdvanceGrid);
            app.AutoIntegrationPeakEditFieldLabel.Tooltip = {'Default: 85% of spectrometer’s capability '};
            app.AutoIntegrationPeakEditFieldLabel.Text = 'Auto Integration Peak';
            app.AutoIntegrationPeakEditField = uieditfield(app.AdvanceGrid, 'numeric');
            app.AutoIntegrationPeakEditField.Limits = [0 1];
            app.AutoIntegrationPeakEditField.ValueChangedFcn = createCallbackFcn(app, @AutoIntegrationPeakEditFieldValueChanged, true);
            app.AutoIntegrationPeakEditField.Tooltip = {''};
            app.AutoIntegrationPeakEditField.Value = 0.85;
            app.AutoSavePrefixEditFieldLabel = uilabel(app.AdvanceGrid);
            app.AutoSavePrefixEditFieldLabel.Text = 'Auto Save Prefix';
            app.AutoSavePrefixEditField = uieditfield(app.AdvanceGrid, 'text');
            app.AutoSavePrefixEditField.ValueChangedFcn = createCallbackFcn(app, @AutoSavePrefixEditFieldValueChanged, true);
            app.AutoSaveIntervalEditFieldLabel = uilabel(app.AdvanceGrid);
            app.AutoSaveIntervalEditFieldLabel.Tooltip = {'Automatic saving of spectrum files every interval, set to 0 to stop auto saving.'; 'Default:300s'};
            app.AutoSaveIntervalEditFieldLabel.Text = 'Auto Save Interval';
            app.AutoSaveIntervalEditField = uieditfield(app.AdvanceGrid, 'numeric');
            app.AutoSaveIntervalEditField.Limits = [0 Inf];
            app.AutoSaveIntervalEditField.ValueChangedFcn = createCallbackFcn(app, @AutoSaveIntervalEditFieldValueChanged, true);
            app.AutoSaveIntervalEditField.Tooltip = {''};
            app.AutoSaveIntervalEditField.Value = 300;
            app.RimShadingAngleEditFieldLabel = uilabel(app.AdvanceGrid);
            app.RimShadingAngleEditFieldLabel.Tooltip = {'Adjust to subtract spectra above the corresponding SZA from the time series.'};
            app.RimShadingAngleEditFieldLabel.Text = 'Rim Shading Angle';
            app.RimShadingAngleEditField = uieditfield(app.AdvanceGrid, 'numeric');
            app.RimShadingAngleEditField.Limits = [0 Inf];
            app.RimShadingAngleEditField.ValueChangedFcn = createCallbackFcn(app, @RimShadingAngleEditFieldValueChanged, true);
            app.RimShadingAngleEditField.Tooltip = {''};
            app.RimShadingAngleEditField.Value = 75;
            app.SetToDefaultButton = uibutton(app.AdvanceGrid, 'push');
            app.SetToDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @SetToDefaultButtonPushed, true);
            app.SetToDefaultButton.Text = 'Set To Default';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OmniSpectrometer_wrapper_updated

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end